classdef EnvironmentEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PointTypeDropDown         matlab.ui.control.DropDown
        PointTypeDropDownLabel    matlab.ui.control.Label
        SelectPointDropDown       matlab.ui.control.DropDown
        SelectPointDropDownLabel  matlab.ui.control.Label
        AddPointButton            matlab.ui.control.Button
        EditPointButton           matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        ExitButton                matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        DeletePointButton         matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mainApp % Description
        environment %class that has all environment info
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, environment)
               app.mainApp = mainApp;
               app.environment = environment;
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            % Enable the button in main app, if the app is
            % still open
            if isvalid(app.mainApp)
                app.mainApp.EditEnvironmentButton.Enable = "on";
            end
            
            % Delete the dialog box 
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DeletePointButton = uibutton(app.UIFigure, 'push');
            app.DeletePointButton.Text = 'Delete Point';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Text = 'Save';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Num'; 'Type'; 'Position'; 'Velocity'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false true true false false];
            app.EditPointButton = uibutton(app.UIFigure, 'push');
            app.EditPointButton.Text = 'Edit Point';
            app.AddPointButton = uibutton(app.UIFigure, 'push');
            app.AddPointButton.Text = 'Add Point';
            app.SelectPointDropDownLabel = uilabel(app.UIFigure);
            app.SelectPointDropDownLabel.Text = 'Select Point';
            app.SelectPointDropDown = uidropdown(app.UIFigure);
            app.PointTypeDropDownLabel = uilabel(app.UIFigure);
            app.PointTypeDropDownLabel.Text = 'Point Type';
            app.PointTypeDropDown = uidropdown(app.UIFigure);
            app.PointTypeDropDown.Items = {'Benign Object', 'Emission Object'};
            app.PointTypeDropDown.Value = 'Benign Object';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EnvironmentEditor(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end