classdef FMCWApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        NoConfigFileSetLabel        matlab.ui.control.Label
        LoadConfigButton            matlab.ui.control.Button
        OpenConfigButton            matlab.ui.control.Button
        ResetScenerioButton         matlab.ui.control.Button
        SaveScenerioButton          matlab.ui.control.Button
        GenerateScenerioButton      matlab.ui.control.StateButton
        EditRadarButton             matlab.ui.control.StateButton
        EditEnvironmentButton       matlab.ui.control.Button
        Results                     matlab.ui.container.Panel
        ClearButton                 matlab.ui.control.Button
        SwitchtoVelocityButton      matlab.ui.control.StateButton
        SaveDataButton              matlab.ui.control.Button
        ViewSpectrumAnalyzerButton  matlab.ui.control.Button
        XvsPowerMap                 matlab.ui.control.UIAxes
        HeatMap                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % instance of FMCWSim class
        radarSim; 
        %currently opened file
        loadedFile;
        %other windows
        radarEditor;
    end
    
    methods (Access = public)
        function updateRadar(app, params)
            % Store inputs as properties
            app.radarSim.radar = app.radarSim.radar.setRadar(params);
            % Re-enable the Plot Options button
            app.EditRadarButton.Enable = "on";
        end
        %function importRadarData(app, radarInfo)
        %   radarSim.() = radarInfo.();
        %end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function
        function HeatMapButtonDown(app, event)
            
        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
            % Prompt user to select a .mat file
            [file, path] = uigetfile('*.mat', 'Select Configuration File');
        
            % If user did not cancel
            if ischar(file)
                % Load the .mat file and display its contents
                app.loadedFile = file; 
                config_file = fullfile(path, file);
                app.radarSim = FMCWSim(config_file);
                app.NoConfigFileSetLabel.Text = string(app.loadedFile) + " Loaded";
            end
        end

        % Value changed function: GenerateScenerioButton
        function GenerateScenerioButtonValueChanged(app, event)
            if isempty(app.radarSim)
                uialert(app.UIFigure, 'Please load a configuration first.', 'Error');
                return;
            end
        
            %added heat map
            Nsweep = 64; % You can allow the user to input this value
            xr = app.radarSim.simulate(Nsweep);
        
            % Clear previous plots on the axes
            cla(app.HeatMap);
        
            % Plotting the range-Doppler response on the existing HeatMap axes
            app.radarSim.plotRangeDoppler(xr, app.HeatMap);

            %add range power graph
            app.radarSim.plotRangeVsPower(xr, app.XvsPowerMap);
        end

        % Button pushed function: ResetScenerioButton
        function ResetScenerioButtonPushed(app, event)
            clear radarSim;
        end

        % Button pushed function: SaveScenerioButton
        function SaveScenerioButtonPushed(app, event)
            %check to see if scenerio is ready
            if isempty(app.radarSim)
                uialert(app.UIFigure, 'Please load a configuration first.', 'Error');
                return;
            end

            %get file name
            prompt = {"Enter config file name:"};
            dlgtitle = 'Load Config Input';
            fieldsize = [1 50];  % Size of the input field
            definput = {'radar_config.mat'};  % Default file name

            save_file = inputdlg(prompt, dlgtitle, fieldsize, definput);
            save_file = save_file{1};

            primary = app.radarSim.radar.saveRadar;

            saveEnvironment = app.radarSim.environment.saveEnvironment;
            % Convert benign_objects and emission_objects (which are cells) to structs
            benign_objects = saveEnvironment.benign_objects;
            emission_objects = saveEnvironment.emission_objects;

            save(save_file, 'primary', 'emission_objects', 'benign_objects');
        end

        % Button pushed function: OpenConfigButton
        function OpenConfigButtonPushed(app, event)
    % Prompt user to select a .mat file
        [file, path] = uigetfile('*.mat', 'Select Configuration File');
        
        % If user did not cancel
        if ischar(file)
            % Load the .mat file and display its contents
            config_file = fullfile(path, file);
            data = load(config_file);
            
            % Create a new figure window to display the contents
            fig = uifigure('Name', 'MAT File Contents', 'Position', [100, 100, 400, 300]);
            uitextarea(fig, 'Value', evalc('disp(data)'), 'Position', [10, 10, 380, 280]);
        end
        end

        % Value changed function: EditRadarButton
        function EditRadarButtonValueChanged(app, event)
            % Disable Plot Options button while dialog is open
            app.EditRadarButton.Enable = "off";

            % Get radar information
            radar = app.radarSim.radar;
            
            % Open the options dialog and pass inputs
            app.radarEditor = RadarEditor(app,radar);
        end

        % Close request function: UIFigure
        function CloseRequest(app, event)
            delete(app.radarEditor)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequest, true);
            app.Results = uipanel(app.UIFigure);
            app.Results.Title = 'Outputs';
            app.HeatMap = uiaxes(app.Results);
            title(app.HeatMap, 'Range Speed Response')
            xlabel(app.HeatMap, 'X')
            ylabel(app.HeatMap, 'Y')
            zlabel(app.HeatMap, 'Z')
            app.XvsPowerMap = uiaxes(app.Results);
            title(app.XvsPowerMap, 'Range vs Power Graph')
            xlabel(app.XvsPowerMap, 'X')
            ylabel(app.XvsPowerMap, 'Y')
            zlabel(app.XvsPowerMap, 'Z')
            app.ViewSpectrumAnalyzerButton = uibutton(app.Results, 'push');
            app.ViewSpectrumAnalyzerButton.Text = 'View Spectrum Analyzer';
            app.SaveDataButton = uibutton(app.Results, 'push');
            app.SaveDataButton.Text = 'Save Data';
            app.SwitchtoVelocityButton = uibutton(app.Results, 'state');
            app.SwitchtoVelocityButton.Text = 'Switch to Velocity';
            app.ClearButton = uibutton(app.Results, 'push');
            app.ClearButton.Text = 'Clear';
            app.EditEnvironmentButton = uibutton(app.UIFigure, 'push');
            app.EditEnvironmentButton.Text = 'Edit Environment';
            app.EditRadarButton = uibutton(app.UIFigure, 'state');
            app.EditRadarButton.ValueChangedFcn = createCallbackFcn(app, @EditRadarButtonValueChanged, true);
            app.EditRadarButton.Text = 'Edit Radar';
            app.GenerateScenerioButton = uibutton(app.UIFigure, 'state');
            app.GenerateScenerioButton.ValueChangedFcn = createCallbackFcn(app, @GenerateScenerioButtonValueChanged, true);
            app.GenerateScenerioButton.Text = 'Generate Scenerio';
            app.SaveScenerioButton = uibutton(app.UIFigure, 'push');
            app.SaveScenerioButton.ButtonPushedFcn = createCallbackFcn(app, @SaveScenerioButtonPushed, true);
            app.SaveScenerioButton.Text = 'Save Scenerio';
            app.ResetScenerioButton = uibutton(app.UIFigure, 'push');
            app.ResetScenerioButton.ButtonPushedFcn = createCallbackFcn(app, @ResetScenerioButtonPushed, true);
            app.ResetScenerioButton.Text = 'Reset Scenerio';
            app.OpenConfigButton = uibutton(app.UIFigure, 'push');
            app.OpenConfigButton.ButtonPushedFcn = createCallbackFcn(app, @OpenConfigButtonPushed, true);
            app.OpenConfigButton.Text = 'Open Config';
            app.LoadConfigButton = uibutton(app.UIFigure, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Text = 'Load Config';
            app.NoConfigFileSetLabel = uilabel(app.UIFigure);
            app.NoConfigFileSetLabel.Text = 'No Config File Set';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FMCWApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end